classdef interfaz_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ComunicacionTab                matlab.ui.container.Tab
        Image5                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Warnings                       matlab.ui.control.TextArea
        Lamp                           matlab.ui.control.Lamp
        BaudRate                       matlab.ui.control.EditField
        BaudiosEditFieldLabel          matlab.ui.control.Label
        SerialPort                     matlab.ui.control.EditField
        PuertoEditFieldLabel           matlab.ui.control.Label
        DetenercomunicacionButton      matlab.ui.control.Button
        IniciarcomunicacionButton      matlab.ui.control.Button
        ControlbrazosystepperTab       matlab.ui.container.Tab
        Image6                         matlab.ui.control.Image
        Lamp_2                         matlab.ui.control.Lamp
        APAGARButton                   matlab.ui.control.Button
        Image4                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        EnviarelcontrlalJoystckButton  matlab.ui.control.Button
        MensajeLlegada                 matlab.ui.control.TextArea
        EnviardatosencontroldevelociadadButton  matlab.ui.control.Button
        EnviardatosencontroldeposicionButton  matlab.ui.control.Button
        tEditField                     matlab.ui.control.NumericEditField
        tEditFieldLabel                matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        fig_trayectoria                matlab.ui.control.UIAxes
        ElectroimanTab                 matlab.ui.container.Tab
        Image7                         matlab.ui.control.Image
        TextoElectroiman               matlab.ui.control.TextArea
        Lamp_3                         matlab.ui.control.Lamp
        imagen_elctroiman              matlab.ui.control.Image
        APAGARButton_2                 matlab.ui.control.Button
        ApagarelectroimanButton        matlab.ui.control.Button
        EncenderelectroimanButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        puerto_serie; % Description
        x_anterior=0
       y_anterior=0
    end
    
    methods (Access = private)
        
        function read_serialData(app,src,event)
        msg = fscanf(app.puerto_serie,'%f,%f');
        app.fig_trayectoria.XLim = [-100,100];
        app.fig_trayectoria.YLim = [-300,300];

        if (abs(msg(1)-app.x_anterior) > 0.5 ) || (abs(msg(2)-app.y_anterior) > 0.5 )
                       plot(app.fig_trayectoria,msg(1),msg(2),'-o','MarkerSize',2)
                       app.x_anterior = msg(1);
                       app.y_anterior = msg(2);
                       app.MensajeLlegada.Value = "LLegando...";
                       app.Lamp_2.Color = [1,0,0];

        else
            app.MensajeLlegada.Value = "Posicion alcazada correctamente.";
            app.Lamp_2.Color = [0,1,0];

        end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnviardatosencontroldeposicionButton
        function EnviardatosencontroldeposicionButtonPushed(app, event)
          
            x = num2str(app.xEditField.Value);
            y = num2str(app.yEditField.Value);
            z = num2str(app.zEditField.Value);
            t = num2str(app.tEditField.Value);
            pos=['3,','1,',x,',',y,',',z,',',t];

            fprintf(app.puerto_serie,pos);
    
        end

        % Button pushed function: IniciarcomunicacionButton
        function IniciarcomunicacionButtonPushed(app, event)
            close all;
            clc;
            baudios = str2double(app.BaudRate.Value);
            app.puerto_serie = serialport(app.SerialPort.Value,baudios); 
            configureTerminator(app.puerto_serie,"LF");
            configureCallback(app.puerto_serie,"terminator",@(src,event) read_serialData(app,src,event));
             try
                fopen(app.puerto_serie);
                app.Lamp.Color = [0,1,0];
                app.Warnings.Value = "Arduino conectado correctamente.";
             catch
                 warning("Fallo en la conexion...");
                 app.Warnings.Value = "Fallo en la conexion.";
                 app.Lamp.Color = [1,0,0];
             end
           

        end

        % Button pushed function: DetenercomunicacionButton
        function DetenercomunicacionButtonPushed(app, event)
            delete(app.puerto_serie);
            app.Lamp.Color = [1,0,0];
        end

        % Button pushed function: EnviardatosencontroldevelociadadButton
        function EnviardatosencontroldevelociadadButtonPushed(app, event)
            x = num2str(app.xEditField.Value);
            y = num2str(app.yEditField.Value);
            z = num2str(app.zEditField.Value);
            t = num2str(app.tEditField.Value);
            vel=['4,','1,',x,',',y,',',z,',',t];
            fprintf(app.puerto_serie,vel);
        end

        % Button pushed function: EnviarelcontrlalJoystckButton
        function EnviarelcontrlalJoystckButtonPushed(app, event)
            msg = ['2,','1,','0',',0',',0'];
            fprintf(app.puerto_serie,msg);
        end

        % Button pushed function: APAGARButton
        function APAGARButtonPushed(app, event)
            msg = ['0,','1,','0',',0',',0'];
            fprintf(app.puerto_serie,msg);
        end

        % Button pushed function: APAGARButton_2
        function APAGARButton_2Pushed(app, event)
            msg = ['0,','1,','0',',0',',0'];
            fprintf(app.puerto_serie,msg);
        end

        % Button pushed function: EncenderelectroimanButton
        function EncenderelectroimanButtonPushed(app, event)
            msg = ['0,','0,','0',',0',',0'];
            app.Lamp_3.Color = [0,1,0];
            app.TextoElectroiman.Value = "Electroiman encendido.";
            fprintf(app.puerto_serie,msg);
        end

        % Button pushed function: ApagarelectroimanButton
        function ApagarelectroimanButtonPushed(app, event)
            msg = ['0,','1,','0',',0',',0'];
            app.Lamp_3.Color = [1,0,0];
            app.TextoElectroiman.Value = "Electroiman apagado.";
            fprintf(app.puerto_serie,msg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComunicacionTab = uitab(app.TabGroup);
            app.ComunicacionTab.Title = 'Comunicacion';
            app.IniciarcomunicacionButton = uibutton(app.ComunicacionTab, 'push');
            app.IniciarcomunicacionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcomunicacionButtonPushed, true);
            app.IniciarcomunicacionButton.Text = 'Iniciar Comunicacion';
            app.DetenercomunicacionButton = uibutton(app.ComunicacionTab, 'push');
            app.DetenercomunicacionButton.ButtonPushedFcn = createCallbackFcn(app, @DetenercomunicacionButtonPushed, true);
            app.DetenercomunicacionButton.Text = 'Detener Comunicacion';
            app.PuertoEditFieldLabel = uilabel(app.ComunicacionTab);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.SerialPort = uieditfield(app.ComunicacionTab, 'text');
            app.BaudiosEditFieldLabel = uilabel(app.ComunicacionTab);
            app.BaudiosEditFieldLabel.Text = 'Baudios';
            app.BaudRate = uieditfield(app.ComunicacionTab, 'text');
            app.Lamp = uilamp(app.ComunicacionTab);
            app.Warnings = uitextarea(app.ComunicacionTab);
            app.Image2 = uiimage(app.ComunicacionTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'barri.png');
            app.Image3 = uiimage(app.ComunicacionTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'arduino.png');
            app.Image5 = uiimage(app.ComunicacionTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'LogoETSII2.png');
            app.ControlbrazosystepperTab = uitab(app.TabGroup);
            app.ControlbrazosystepperTab.Title = 'Control brazos y stepper';
            app.fig_trayectoria = uiaxes(app.ControlbrazosystepperTab);
            title(app.fig_trayectoria, 'Trayectoria')
            xlabel(app.fig_trayectoria, 'X')
            ylabel(app.fig_trayectoria, 'Y')
            zlabel(app.fig_trayectoria, 'Z')
            app.xEditFieldLabel = uilabel(app.ControlbrazosystepperTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.ControlbrazosystepperTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.ControlbrazosystepperTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.ControlbrazosystepperTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.ControlbrazosystepperTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.ControlbrazosystepperTab, 'numeric');
            app.tEditFieldLabel = uilabel(app.ControlbrazosystepperTab);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.ControlbrazosystepperTab, 'numeric');
            app.EnviardatosencontroldeposicionButton = uibutton(app.ControlbrazosystepperTab, 'push');
            app.EnviardatosencontroldeposicionButton.ButtonPushedFcn = createCallbackFcn(app, @EnviardatosencontroldeposicionButtonPushed, true);
            app.EnviardatosencontroldeposicionButton.Text = 'Enviar datos en control de posicion';
            app.EnviardatosencontroldevelociadadButton = uibutton(app.ControlbrazosystepperTab, 'push');
            app.EnviardatosencontroldevelociadadButton.ButtonPushedFcn = createCallbackFcn(app, @EnviardatosencontroldevelociadadButtonPushed, true);
            app.EnviardatosencontroldevelociadadButton.Text = 'Enviar datos en control de velociadad';
            app.MensajeLlegada = uitextarea(app.ControlbrazosystepperTab);
            app.EnviarelcontrlalJoystckButton = uibutton(app.ControlbrazosystepperTab, 'push');
            app.EnviarelcontrlalJoystckButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarelcontrlalJoystckButtonPushed, true);
            app.EnviarelcontrlalJoystckButton.Text = 'Enviar el control al joystck';
            app.Image = uiimage(app.ControlbrazosystepperTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'boton.png');
            app.Image4 = uiimage(app.ControlbrazosystepperTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'brazos.png');
            app.APAGARButton = uibutton(app.ControlbrazosystepperTab, 'push');
            app.APAGARButton.ButtonPushedFcn = createCallbackFcn(app, @APAGARButtonPushed, true);
            app.APAGARButton.Text = 'APAGAR';
            app.Lamp_2 = uilamp(app.ControlbrazosystepperTab);
            app.Image6 = uiimage(app.ControlbrazosystepperTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'LogoETSII2.png');
            app.ElectroimanTab = uitab(app.TabGroup);
            app.ElectroimanTab.Title = 'Electroiman';
            app.EncenderelectroimanButton = uibutton(app.ElectroimanTab, 'push');
            app.EncenderelectroimanButton.ButtonPushedFcn = createCallbackFcn(app, @EncenderelectroimanButtonPushed, true);
            app.EncenderelectroimanButton.Text = 'Encender electroiman';
            app.ApagarelectroimanButton = uibutton(app.ElectroimanTab, 'push');
            app.ApagarelectroimanButton.ButtonPushedFcn = createCallbackFcn(app, @ApagarelectroimanButtonPushed, true);
            app.ApagarelectroimanButton.Text = 'Apagar electroiman';
            app.APAGARButton_2 = uibutton(app.ElectroimanTab, 'push');
            app.APAGARButton_2.ButtonPushedFcn = createCallbackFcn(app, @APAGARButton_2Pushed, true);
            app.APAGARButton_2.Text = 'APAGAR';
            app.imagen_elctroiman = uiimage(app.ElectroimanTab);
            app.imagen_elctroiman.ImageSource = fullfile(pathToMLAPP, 'electroiman.png');
            app.Lamp_3 = uilamp(app.ElectroimanTab);
            app.TextoElectroiman = uitextarea(app.ElectroimanTab);
            app.Image7 = uiimage(app.ElectroimanTab);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'LogoETSII2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end